classdef project2006027_2006028 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        BangladeshUniversityOfEngineeringandTechnologyLabel  matlab.ui.control.Label
        UserCanthrowballfromanyheightLabel  matlab.ui.control.Label
        MeasuresalltheaboveparameterswithorwithoutmediumresistanceLabel  matlab.ui.control.Label
        MeasuresneededinitialvelocitytoreachacertainpointLabel  matlab.ui.control.Label
        MeasuresvelocityatanytimeLabel  matlab.ui.control.Label
        MeasurestheRangeMaximumHeightTimeoftravellingLabel  matlab.ui.control.Label
        ShowsAnimationoftheprojectileLabel  matlab.ui.control.Label
        FeaturesLabel                   matlab.ui.control.Label
        Level2Term1SectionA1Label       matlab.ui.control.Label
        KawsainBinSalimID2006028Label   matlab.ui.control.Label
        DevelopedByLabel                matlab.ui.control.Label
        ProjectileMotionSimulatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Part1Tab                        matlab.ui.container.Tab
        ProjectileMotionSimulatorPanel  matlab.ui.container.Panel
        VelocityMeasuringatanytimeLabel  matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        OUTPUTLabel                     matlab.ui.control.Label
        MeasureVelocityButton           matlab.ui.control.Button
        Velocityafterthattimems1EditField  matlab.ui.control.NumericEditField
        Velocityafterthattimems1EditFieldLabel  matlab.ui.control.Label
        VelocityaftertimesEditField     matlab.ui.control.NumericEditField
        VelocityaftertimesEditFieldLabel  matlab.ui.control.Label
        InitialHeightmEditField         matlab.ui.control.NumericEditField
        InitialHeightmEditFieldLabel    matlab.ui.control.Label
        TravellingTimesEditField        matlab.ui.control.NumericEditField
        TravellingTimesEditFieldLabel   matlab.ui.control.Label
        MaximumHeightmEditField         matlab.ui.control.NumericEditField
        MaximumHeightmEditFieldLabel    matlab.ui.control.Label
        RangemEditField                 matlab.ui.control.NumericEditField
        RangemEditFieldLabel            matlab.ui.control.Label
        LAUNCHButton                    matlab.ui.control.Button
        ProjectionAngledegreeEditField  matlab.ui.control.NumericEditField
        ProjectionAngledegreeEditFieldLabel  matlab.ui.control.Label
        InitialVelocityms1EditField     matlab.ui.control.NumericEditField
        InitialVelocityms1EditFieldLabel  matlab.ui.control.Label
        GravitationalAccelerationms2EditField  matlab.ui.control.NumericEditField
        GravitationalAccelerationms2EditFieldLabel  matlab.ui.control.Label
        Mediumdensitykgm3EditField      matlab.ui.control.NumericEditField
        Mediumdensitykgm3EditFieldLabel  matlab.ui.control.Label
        Dragcoefficient04705EditField   matlab.ui.control.NumericEditField
        Dragcoefficient04705EditFieldLabel  matlab.ui.control.Label
        DiametermEditField              matlab.ui.control.NumericEditField
        DiametermEditFieldLabel         matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Part2Tab                        matlab.ui.container.Tab
        InitialheightmEditField         matlab.ui.control.NumericEditField
        InitialheightmEditFieldLabel    matlab.ui.control.Label
        MasskgEditField_2               matlab.ui.control.NumericEditField
        MasskgEditField_2Label          matlab.ui.control.Label
        DiametermEditField_2            matlab.ui.control.NumericEditField
        DiametermEditField_2Label       matlab.ui.control.Label
        InitialVecocityNeededtoHitthattargetms1EditField  matlab.ui.control.NumericEditField
        InitialVecocityNeededtoHitthattargetms1EditFieldLabel  matlab.ui.control.Label
        MeasureButton                   matlab.ui.control.Button
        GravitationalAccelerationms2EditField_2  matlab.ui.control.NumericEditField
        GravitationalAccelerationms2EditField_2Label  matlab.ui.control.Label
        forhittingatargetLabel          matlab.ui.control.Label
        ProjectionAngledegreeEditField_2  matlab.ui.control.NumericEditField
        ProjectionAngledegreeEditField_2Label  matlab.ui.control.Label
        VerticalDisplacementmEditField  matlab.ui.control.NumericEditField
        VerticalDisplacementmEditFieldLabel  matlab.ui.control.Label
        HorizontalDisplacementmEditField  matlab.ui.control.NumericEditField
        HorizontalDisplacementmEditFieldLabel  matlab.ui.control.Label
        SetthetargetLabel               matlab.ui.control.Label
        DeterminingTheinitialVelocityLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LAUNCHButton
        function LAUNCHButtonPushed(app, event)
             m=app.MasskgEditField.Value;
            d=app.DiametermEditField.Value;
            C=app.Dragcoefficient04705EditField.Value;
            rho=app.Mediumdensitykgm3EditField.Value;
            g=app.GravitationalAccelerationms2EditField.Value;
            v=app.InitialVelocityms1EditField.Value;
            theta=app.ProjectionAngledegreeEditField.Value;
            yin=app.InitialHeightmEditField.Value;
            A=pi*(d)^2/4;
            D=rho*C*A/2;
            delta_t= .01;
            x(1)=0;
            y(1)=yin;
            vx=v*cosd(theta);
            vy=v*sind(theta);
            t(1)=0 ;
            %Start Loop
    i=1;
    while min(y)> -0.001
        ax=-(D/m)*v*vx;
        ay=-g-(D/m)*v*vy;
        vx=vx+ax*delta_t;
        vy=vy+ay*delta_t;
        x(i+1)=x(i)+vx*delta_t+.5*ax*delta_t^2;
        y(i+1)=y(i)+vy*delta_t+.5*ay*delta_t^2;
        t(i+1)=t(i)+delta_t;
        i=i+1;
        plot(app.UIAxes,x,y);
        pause(0.001);
    end
    app.RangemEditField.Value=max(x);
    app.MaximumHeightmEditField.Value=max(y);
    app.TravellingTimesEditField.Value=max(t);
        end

        % Button pushed function: MeasureVelocityButton
        function MeasureVelocityButtonPushed(app, event)
             m=app.MasskgEditField.Value;
            d=app.DiametermEditField.Value;
            C=app.Dragcoefficient04705EditField.Value;
            rho=app.Mediumdensitykgm3EditField.Value;
            g=app.GravitationalAccelerationms2EditField.Value;
            v=app.InitialVelocityms1EditField.Value;
            theta=app.ProjectionAngledegreeEditField.Value;
            yin=app.InitialHeightmEditField.Value;
            A=pi*(d)^2/4;
            D=rho*C*A/2;
            delta_t= .01;
             x(1)=0;
            y(1)=yin;
             vx=v*cosd(theta);
            vy=v*sind(theta);
            t(1)=0 ;
             i=1;
    while min(y)> -0.001
        ax=-(D/m)*v*vx;
        ay=-g-(D/m)*v*vy;
        vx=vx+ax*delta_t;
        vy=vy+ay*delta_t;
        x(i+1)=x(i)+vx*delta_t+.5*ax*delta_t^2;
        y(i+1)=y(i)+vy*delta_t+.5*ay*delta_t^2;
        t(i+1)=t(i)+delta_t;
        i=i+1;
        %plot(app.UIAxes,x,y);
        %pause(0.001);
    end
            tin=app.VelocityaftertimesEditField.Value;
            vx=v*cosd(theta);
            vy=v*sind(theta);
    for j=1:length(x)
        vx=vx+ax*delta_t;
        vy=vy+ay*delta_t;
        if t(j)>tin
           app.Velocityafterthattimems1EditField.Value=sqrt(vx^2+vy^2);
           break;
        end
        if tin>=max(t)
            app.Velocityafterthattimems1EditField.Value=0;
        end
    end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            x=app.HorizontalDisplacementmEditField.Value;
            y=app.VerticalDisplacementmEditField.Value;
            yi=app.InitialheightmEditField.Value;
            y=y-yi;
             g=app.GravitationalAccelerationms2EditField_2.Value;
           d=app.DiametermEditField_2.Value;
           m=app.MasskgEditField_2.Value;
            A = pi * (d/2)^2; % cross-sectional area of projectile
            theta = deg2rad(app.ProjectionAngledegreeEditField_2.Value);
        v=sqrt((g*x^2)/(2*cos(theta)*cos(theta)*(x*tan(theta)-y)));
        app.InitialVecocityNeededtoHitthattargetms1EditField.Value=v;
            delta_t= .01;
            X(1)=0;
            Y(1)=yi;
            rho=0;
            cd=0;
            vx=v*cos(theta);
            vy=v*sin(theta);
            D=rho*cd*A/2;
            t(1)=0 ;            

             %Start Loop
    i=1;
    while max(X)<= x && Y(i)>=0
        ax=-(D/m)*v*vx;
        ay=-g-(D/m)*v*vy;
        vx=vx+ax*delta_t;
        vy=vy+ay*delta_t;
        X(i+1)=X(i)+vx*delta_t+.5*ax*delta_t^2;
        Y(i+1)=Y(i)+vy*delta_t+.5*ay*delta_t^2;
        t(i+1)=t(i)+delta_t;
        i=i+1;
        plot(app.UIAxes2,X,Y);
        pause(0.001);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Image = uiimage(app.IntroTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'projectpic.png');
            app.ProjectileMotionSimulatorLabel = uilabel(app.IntroTab);
            app.ProjectileMotionSimulatorLabel.Text = 'Projectile Motion Simulator';
            app.DevelopedByLabel = uilabel(app.IntroTab);
            app.DevelopedByLabel.Text = ' Developed By-';
            app.KawsainBinSalimID2006028Label = uilabel(app.IntroTab);
            app.KawsainBinSalimID2006028Label.Text = 'Kawsain Bin Salim(ID-2006028)';
            app.Level2Term1SectionA1Label = uilabel(app.IntroTab);
            app.Level2Term1SectionA1Label.Text = 'Level-2 Term-1 Section:A1';
            app.FeaturesLabel = uilabel(app.IntroTab);
            app.FeaturesLabel.Text = 'Features-';
            app.ShowsAnimationoftheprojectileLabel = uilabel(app.IntroTab);
            app.ShowsAnimationoftheprojectileLabel.Text = '>Shows Animation of the projectile';
            app.MeasurestheRangeMaximumHeightTimeoftravellingLabel = uilabel(app.IntroTab);
            app.MeasurestheRangeMaximumHeightTimeoftravellingLabel.Text = '>Measures the Range,Maximum Height,Time of travelling';
            app.MeasuresvelocityatanytimeLabel = uilabel(app.IntroTab);
            app.MeasuresvelocityatanytimeLabel.Text = '>Measures velocity at any time';
            app.MeasuresneededinitialvelocitytoreachacertainpointLabel = uilabel(app.IntroTab);
            app.MeasuresneededinitialvelocitytoreachacertainpointLabel.Text = '>Measures needed initial velocity to reach a certain point';
            app.MeasuresalltheaboveparameterswithorwithoutmediumresistanceLabel = uilabel(app.IntroTab);
            app.MeasuresalltheaboveparameterswithorwithoutmediumresistanceLabel.Text = '>Measures all the above parameters with or without medium resistance ';
            app.UserCanthrowballfromanyheightLabel = uilabel(app.IntroTab);
            app.UserCanthrowballfromanyheightLabel.Text = '>User Can throw ball from any height';
            app.BangladeshUniversityOfEngineeringandTechnologyLabel = uilabel(app.IntroTab);
            app.BangladeshUniversityOfEngineeringandTechnologyLabel.Text = 'Bangladesh University Of Engineering and Technology';
            app.Part1Tab = uitab(app.TabGroup);
            app.Part1Tab.Title = 'Part1';
            app.ProjectileMotionSimulatorPanel = uipanel(app.Part1Tab);
            app.ProjectileMotionSimulatorPanel.Title = 'Projectile Motion Simulator ';
            app.UIAxes = uiaxes(app.ProjectileMotionSimulatorPanel);
            title(app.UIAxes, 'Animation')
            xlabel(app.UIAxes, 'Distance(m)')
            ylabel(app.UIAxes, 'Height(m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 500];
            app.UIAxes.YLim = [0 500];
            app.MasskgEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.MasskgEditFieldLabel.Text = 'Mass(kg)';
            app.MasskgEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.DiametermEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.DiametermEditFieldLabel.Text = 'Diameter(m)';
            app.DiametermEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.Dragcoefficient04705EditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.Dragcoefficient04705EditFieldLabel.Text = 'Drag coefficient(0.47-0.5)';
            app.Dragcoefficient04705EditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.Mediumdensitykgm3EditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.Mediumdensitykgm3EditFieldLabel.Text = 'Medium density(kgm^-3)';
            app.Mediumdensitykgm3EditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.GravitationalAccelerationms2EditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.GravitationalAccelerationms2EditFieldLabel.Text = 'Gravitational Acceleration(ms^-2)';
            app.GravitationalAccelerationms2EditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.InitialVelocityms1EditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.InitialVelocityms1EditFieldLabel.Text = 'Initial Velocity(ms^-1)';
            app.InitialVelocityms1EditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.ProjectionAngledegreeEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.ProjectionAngledegreeEditFieldLabel.Text = 'Projection Angle(degree)';
            app.ProjectionAngledegreeEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.LAUNCHButton = uibutton(app.ProjectileMotionSimulatorPanel, 'push');
            app.LAUNCHButton.ButtonPushedFcn = createCallbackFcn(app, @LAUNCHButtonPushed, true);
            app.LAUNCHButton.Text = 'LAUNCH!';
            app.RangemEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.RangemEditFieldLabel.Text = 'Range(m)';
            app.RangemEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.MaximumHeightmEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.MaximumHeightmEditFieldLabel.Text = 'Maximum Height(m)';
            app.MaximumHeightmEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.TravellingTimesEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.TravellingTimesEditFieldLabel.Text = 'Travelling Time(s)';
            app.TravellingTimesEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.InitialHeightmEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.InitialHeightmEditFieldLabel.Text = 'Initial Height(m)';
            app.InitialHeightmEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.VelocityaftertimesEditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.VelocityaftertimesEditFieldLabel.Text = 'Velocity after time(s)';
            app.VelocityaftertimesEditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.Velocityafterthattimems1EditFieldLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.Velocityafterthattimems1EditFieldLabel.Text = 'Velocity after that time(ms^-1)';
            app.Velocityafterthattimems1EditField = uieditfield(app.ProjectileMotionSimulatorPanel, 'numeric');
            app.MeasureVelocityButton = uibutton(app.ProjectileMotionSimulatorPanel, 'push');
            app.MeasureVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureVelocityButtonPushed, true);
            app.MeasureVelocityButton.Text = 'Measure Velocity';
            app.OUTPUTLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.InputLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.InputLabel.Text = 'Input';
            app.VelocityMeasuringatanytimeLabel = uilabel(app.ProjectileMotionSimulatorPanel);
            app.VelocityMeasuringatanytimeLabel.Text = 'Velocity Measuring at any time';
            app.Part2Tab = uitab(app.TabGroup);
            app.Part2Tab.Title = 'Part2';
            app.UIAxes2 = uiaxes(app.Part2Tab);
            title(app.UIAxes2, 'Animation')
            xlabel(app.UIAxes2, 'Distance(m)')
            ylabel(app.UIAxes2, 'Height(m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 500];
            app.UIAxes2.YLim = [0 500];
            app.DeterminingTheinitialVelocityLabel = uilabel(app.Part2Tab);
            app.DeterminingTheinitialVelocityLabel.Text = 'Determining The initial Velocity';
            app.SetthetargetLabel = uilabel(app.Part2Tab);
            app.SetthetargetLabel.Text = 'Set the target';
            app.HorizontalDisplacementmEditFieldLabel = uilabel(app.Part2Tab);
            app.HorizontalDisplacementmEditFieldLabel.Text = 'Horizontal Displacement(m)';
            app.HorizontalDisplacementmEditField = uieditfield(app.Part2Tab, 'numeric');
            app.VerticalDisplacementmEditFieldLabel = uilabel(app.Part2Tab);
            app.VerticalDisplacementmEditFieldLabel.Text = 'Vertical Displacement(m)';
            app.VerticalDisplacementmEditField = uieditfield(app.Part2Tab, 'numeric');
            app.ProjectionAngledegreeEditField_2Label = uilabel(app.Part2Tab);
            app.ProjectionAngledegreeEditField_2Label.Text = 'Projection Angle(degree)';
            app.ProjectionAngledegreeEditField_2 = uieditfield(app.Part2Tab, 'numeric');
            app.forhittingatargetLabel = uilabel(app.Part2Tab);
            app.forhittingatargetLabel.Text = 'for hitting a target';
            app.GravitationalAccelerationms2EditField_2Label = uilabel(app.Part2Tab);
            app.GravitationalAccelerationms2EditField_2Label.Text = 'Gravitational Acceleration(ms^-2)';
            app.GravitationalAccelerationms2EditField_2 = uieditfield(app.Part2Tab, 'numeric');
            app.MeasureButton = uibutton(app.Part2Tab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.InitialVecocityNeededtoHitthattargetms1EditFieldLabel = uilabel(app.Part2Tab);
            app.InitialVecocityNeededtoHitthattargetms1EditFieldLabel.Text = 'Initial Vecocity Needed to Hit that target(ms^-1)';
            app.InitialVecocityNeededtoHitthattargetms1EditField = uieditfield(app.Part2Tab, 'numeric');
            app.DiametermEditField_2Label = uilabel(app.Part2Tab);
            app.DiametermEditField_2Label.Text = 'Diameter(m)';
            app.DiametermEditField_2 = uieditfield(app.Part2Tab, 'numeric');
            app.MasskgEditField_2Label = uilabel(app.Part2Tab);
            app.MasskgEditField_2Label.Text = 'Mass(kg)';
            app.MasskgEditField_2 = uieditfield(app.Part2Tab, 'numeric');
            app.InitialheightmEditFieldLabel = uilabel(app.Part2Tab);
            app.InitialheightmEditFieldLabel.Text = 'Initial height(m)';
            app.InitialheightmEditField = uieditfield(app.Part2Tab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2006027_2006028

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end